classdef GITT3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        extract2Button             matlab.ui.control.Button
        EditField                  matlab.ui.control.NumericEditField
        andismaxorminLabel         matlab.ui.control.Label
        smoothchoiceDropDown       matlab.ui.control.DropDown
        smoothchoiceDropDownLabel  matlab.ui.control.Label
        stepsmoothEditField        matlab.ui.control.NumericEditField
        stepsmoothEditFieldLabel   matlab.ui.control.Label
        trendDropDown              matlab.ui.control.DropDown
        trendDropDownLabel         matlab.ui.control.Label
        SmooothDegreeSlider        matlab.ui.control.Slider
        SmooothDegreeSliderLabel   matlab.ui.control.Label
        slop2EditField             matlab.ui.control.NumericEditField
        slop2EditFieldLabel        matlab.ui.control.Label
        slop1EditField             matlab.ui.control.NumericEditField
        slop1EditFieldLabel        matlab.ui.control.Label
        LoaddataButton             matlab.ui.control.Button
        UIAxes5                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes_5                   matlab.ui.control.UIAxes
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       % Description
      khorooji
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
clearvars smoothslop1 smooth1 smooth2 max1 min1 VR DEt DEs atf1 atf2 left right s S ;

choice=app.trendDropDown.Value;
if choice=="discharge"
[status,sheets] = xlsfinfo('1.xls');
sheet_lengh=length(sheets);
s=cat(1,xlsread('1.xls',2,'D:F'));
 for tt=3:sheet_lengh
     S=xlsread('1.xls',tt,'D:F');
     s=cat(1,s,S);
 end
            smooth_stage=app.stepsmoothEditField.Value;
            smooth_deg=app.SmooothDegreeSlider.Value;
            neglim=-app.slop1EditField.Value;
            poslim=app.slop2EditField.Value;
            smooth_choice=app.smoothchoiceDropDown.Value;

        [x,y]=size(s);

h=s(:,3);
i=1;
j=0;
d=0;
while i<x
   
  while i<x
i=i+1;
    if h(i)~=0
         z=i;
    else
        j=j+1;
        Z(j)=z;
         break   
    end
  end
    while i<x
i=i+1;
    if h(i)==0
         k=i;
    else
         d=d+1;
         K(d)=k;
         break   
    end
    end
end
% baraye shoroo az aval jaryane sefr ya zaryan
Z=Z+1;
K=K+1;
K=[1 K];
len=length(K);
 for i=2:len
     
     if i<len
            if (K(i)-1:K(i+1)-1)==(K(3)-1:K(2)-1)
                jj(:,:,i)=s(K(i)-1:K(i+1)-1,:);
            else
                break
            end
     else
         break
     end
 end    
  
[x1, y1, z1]=size(jj);
% plot(1:x1,jj(:,2,32))
% baraye be ddast avardan andis taghir

for i=1:z1
    max1(i)=max(jj(:,2,i));
    min1(i)=min(jj(:,2,i));
end
%    plot(2:length(max1),max1(2:end),'black')
%    hold on
%    plot(2:length(min1),min1(2:end),'red')
for iii=2:length(min1)
    if min1(iii+1)<min1(iii)
        Min=min1(iii+1);
        andis_min=iii+1;
    else
        break
    end
end
 for i=1:z1-1
    if i<z1
      left(:,:,i)=jj(1:Z(3)-K(3),:,i);
      right(:,:,i)=jj(Z(3)-K(3):K(3)-K(2),:,i);
    else
        break
    end
 end 

%         plot(1:Z(3)-K(3),left(:,2,10))
%         hold on
%         plot(Z(3)-K(3):K(3)-K(2),right(:,2,21))
for e=1:z1-1
smooth1(:,e)=smooth(left(:,2,e),smooth_deg);
  for t=1:Z(3)-K(3)-4
        if t<Z(3)-K(3)
       smoothslop1(t,e)=(smooth1(t+1,e)-smooth1(t,e));
        else 
            break
        end
  end
%    subplot(2,2,1)
%   plot(1:20,smoothslop1(1:20,e),'-+')
%   hold on
end
% title('smooth1')
% hold off
%   plot(1:length(smoothslop1),smoothslop1(:,10))
%  plot(1:length(left(:,2,30)),left(:,2,30))
for e=1:z1-1
smooth2(:,e)=smooth(right(:,2,e),smooth_deg);
  for t=1:Z(3)-K(3)-4
        if t<Z(3)-K(3)
       smoothslop2(t,e)=(smooth2(t+1,e)-smooth2(t,e));
        else 
            break
        end
  end
%   subplot(2,2,2)
%   plot(1:50,smoothslop2(1:50,e))
%   hold on
end
% subplot(2,2,4)
% plot(1:length(smooth1),smooth1(:,25))
%    hold on
%    plot(length(smooth1):length(smooth2)+length(smooth1)-1,smooth2(:,25))
%    hold off
% plot(1:length(smooth2),smooth2(:,5))
%     hold on
% plot(1:length(right(:,2,5)),right(:,2,5))


% title('smooth2')
% hold off
%     plot(1:length(smoothslop2),smoothslop2(:,2))
  
%     plot(1:length(left(:,2,23)),left(:,2,23)) 
%          hold on
%      plot(1:length(smooth1(:,23)),smooth1(:,23))
%   plot(1:length(slopleft),slopleft(:,20))
% for ii=1:z1-1
%     if ii<=andis_min
%          for yy=1:20
%              if smoothslop1(yy,ii)<neglim1
%                 
%              else
% %                  baraye shib manfi smt chap koli chap jozi
%                 atf1(ii)=smooth1(yy,ii);
%                 break
%              end
%         end
%        
%         for yy=1:20
%             if smoothslop2(yy,ii)>poslim1
%                 
%             else
%                 %          baraye shib mosbat smt chap koli rast  jozi
%                atf2(ii)=smooth2(yy,ii);
%                 break
%             end
%         end     
%     else
%          for yy=1:20
%             if smoothslop1(yy,ii)>poslim2
%                 
%             else
%                  atf1(ii)=smooth1(yy,ii);
%                %  baraye shib mosbat smt rast koli chape jozi
%                 break
%             end
%         end
%        
%         for yy=1:20
%             if smoothslop2(yy,ii)<neglim2
%                 
%             else
%               atf2(ii)=smooth2(yy,ii);
%                 %  baraye shib manfi smt rast koli rast jozi
%                 break
%             end
%         end
%     end
% end
for ii=1:z1-1
    if ii<=andis_min
        for yy=1:length(smoothslop1(:,ii))
            if smoothslop1(yy,ii)<neglim
                
            else
                %          baraye shib mosbat smt chap koli rast  jozi
               atf(ii)=smooth1(yy,ii);
               andis_atf(ii)=yy;
                break
            end
        end     
    else
         for yy=1:length(smoothslop1(:,ii))
            if smoothslop1(yy,ii)>poslim
                
            else
                 atf(ii)=smooth1(yy,ii);
                 andis_atf(ii)=yy;
               %  baraye shib mosbat smt rast koli chape jozi
                break
            end
         end
    end
end


if smooth_choice=='chap'
plot(app.UIAxes_5,1:length(smooth1(:,smooth_stage)),smooth1(:,smooth_stage))
hold(app.UIAxes_5,"on")
plot(app.UIAxes_5,andis_atf(smooth_stage),atf(smooth_stage),'o')
plot(app.UIAxes_5,1:length(left(:,2,smooth_stage)),left(:,2,smooth_stage))
hold(app.UIAxes_5,"off")
plot(app.UIAxes5,sqrt(1:length(smooth1(:,smooth_stage))),smooth1(:,smooth_stage))
else
plot(app.UIAxes_5,1:length(smooth2(:,smooth_stage)),smooth2(:,smooth_stage))
hold(app.UIAxes_5,"on")
plot(app.UIAxes_5,1:length(right(:,2,smooth_stage)),right(:,2,smooth_stage))
hold(app.UIAxes_5,"off")
plot(app.UIAxes5,sqrt(1:length(smooth1(:,smooth_stage))),smooth1(:,smooth_stage))
end
r=0;
for i=2:e
    plot(app.UIAxes3,r+andis_atf(i),atf(i),'*')
    hold(app.UIAxes3,"on")
    plot(app.UIAxes3,1+r:length(smooth1(:,i))+r,smooth1(:,i))
    r=r+length(smooth2(:,2));
end
app.EditField.Value=andis_min;
else

[status,sheets] = xlsfinfo('1.xls');
sheet_lengh=length(sheets);
s=cat(1,xlsread('1.xls',2,'D:F'));
 for tt=3:sheet_lengh
     S=xlsread('1.xls',tt,'D:F');
     s=cat(1,s,S);
 end
            smooth_stage=app.stepsmoothEditField.Value;
            smooth_deg=app.SmooothDegreeSlider.Value;
            poslim=app.slop1EditField.Value;
            neglim=-app.slop2EditField.Value;
            smooth_choice=app.smoothchoiceDropDown.Value;
           [x,y]=size(s);
h=s(:,3);
i=1;
j=0;
d=0;
o=1;
while i<x
   
  while i<x
i=i+1;
    if h(i)~=0
         z=i;
    else
        j=j+1;
        Z(j)=z;
         break   
    end
  end
    while i<x
i=i+1;
    if h(i)==0
         k=i;
    else
         d=d+1;
         K(d)=k;
         break   
    end
    end
end
% baraye shoroo az aval jaryane sefr ya zaryan
Z=Z+1;
K=K+1;
K=[1 K];
len=length(K);
 for i=2:len    
     if i<len
            if abs(K(i)-K(i+1))==abs(K(3)-K(2))
            jj(:,:,i)=s(K(i)-1:K(i+1)-1,:);
            else
            jj(:,:,i)=s(K(2)-1:K(3)-1,:);
            end
     else
         break
     end
 end    
%  plot(1:4804,jj(:,2,43))
[x1, y1, z1]=size(jj);

for i=1:z1
    max1(i)=max(jj(:,2,i));
    min1(i)=min(jj(:,2,i));
end
[Max , andis_max]=max(max1);
 for i=1:z1
    if i<z1
      left(:,:,i)=jj(1:Z(3)-K(3),:,i);
      right(:,:,i)=jj(Z(3)-K(3):K(3)-K(2),:,i);
    else
        break
    end
 end 

%         plot(1:Z(1)-1,left(:,2,10))
%         hold on
%         plot(Z(1):K(2)-1,right(:,2,42))
%         hold on 
for e=1:z1-1
smooth1(:,e)=smooth(left(:,2,e),smooth_deg);
  for t=1:Z(1)-4
        if t<length(smooth1(:,1))
       smoothslop1(t,e)=(smooth1(t+1,e)-smooth1(t,e));
        else 
            break
        end
  end
%   plot(1:length(smoothslop1),smoothslop1(:,e))
%   hold on
end
%   plot(1:length(smoothslop1),smoothslop1(:,40))
for e=1:z1-1
smooth2(:,e)=smooth(right(:,2,e),smooth_deg);
  for t=1:Z(1)-4
        if t<Z(1)
       smoothslop2(t,e)=(smooth2(t+1,e)-smooth2(t,e));
        else 
            break
        end
  end
%   plot(1:length(smoothslop2),smoothslop2(:,e))
%   hold on
end
%    plot(1:length(smoothslop2),smoothslop2(:,40))
%     plot(1:1202,left(:,2,23)) 
%          hold on
%      plot(1:length(smooth1),smooth1)
%   plot(1:length(slopleft),slopleft(:,20))
%  plot(1:z1,max1)
%  hold on
%  plot(1:z1,min1)
%  hold on
for ii=1:z1-1
    if ii<=andis_max
        for yy=1:length(smoothslop1(:,ii))
            if smoothslop1(yy,ii)>poslim
                
            else
                atf(ii)=smooth1(yy,ii);
                andis_atf(ii)=yy;
                break
            end
        end

    else
            for yy=1:length(smoothslop1(:,ii))
            if smoothslop1(yy,ii)<neglim
                
            else
                atf(ii)=smooth1(yy,ii);
                andis_atf(ii)=yy;
                break
            end
            end 
    end
end

if smooth_choice=='chap'
plot(app.UIAxes_5,1:length(smooth1(:,smooth_stage)),smooth1(:,smooth_stage))
hold(app.UIAxes_5,"on")
plot(app.UIAxes_5,andis_atf(smooth_stage),atf(smooth_stage),'*')
plot(app.UIAxes_5,1:length(left(:,2,smooth_stage)),left(:,2,smooth_stage))
hold(app.UIAxes_5,"off")
plot(app.UIAxes5,sqrt(1:length(smooth1(:,smooth_stage))),smooth1(:,smooth_stage))
else
plot(app.UIAxes_5,1:length(smooth2(:,smooth_stage)),smooth2(:,smooth_stage))
hold(app.UIAxes_5,"on")
plot(app.UIAxes_5,1:length(right(:,2,smooth_stage)),right(:,2,smooth_stage))
hold(app.UIAxes_5,"off")
plot(app.UIAxes5,sqrt(1:length(smooth1(:,smooth_stage))),smooth1(:,smooth_stage))
end
app.EditField.Value=andis_max;
r=0;
for i=2:e
    plot(app.UIAxes3,r+andis_atf(i),atf(i),'*')
    hold(app.UIAxes3,"on")
    plot(app.UIAxes3,1+r:length(smooth1(:,i))+r,smooth1(:,i))
    r=r+length(smooth2(:,2));
end
end

% all plots are here
plot(app.UIAxes_2,linspace(0,1,length(s(:,2))),s(:,2))


for e=2:z1-1
plot(app.UIAxes_3,1:20,smoothslop1(1:20,e))
hold(app.UIAxes_3,"on")
end
for e=2:z1-1
plot(app.UIAxes_4,1:20,smoothslop2(1:20,e))
hold(app.UIAxes_4,"on")
end
app.khorooji=[max1(1:end-1) min1(1:end-1) atf];

hold(app.UIAxes_2,"off")
hold(app.UIAxes_3,"off")
hold(app.UIAxes_4,"off")
hold(app.UIAxes_5,"off")
hold(app.UIAxes3,"off")
hold(app.UIAxes5,"off")


        end

        % Callback function
        function ExtractButtonPushed(app, event)
            xlswrite('GITT_Ds_charge.xls',app.Ds_charge)
            xlswrite('GITT_Ds_discharge.xls',app.Ds_diss)
        end

        % Button pushed function: extract2Button
        function extract2ButtonPushed(app, event)
            xlswrite('GITT_Max_Min_atf.xls',app.khorooji)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'smoothslop1')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'smoothslop2')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'V')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'atf position')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'V')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'sqrt(t)')
            ylabel(app.UIAxes5, 'V')
            zlabel(app.UIAxes5, 'Z')
            app.LoaddataButton = uibutton(app.UIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.slop1EditFieldLabel = uilabel(app.UIFigure);
            app.slop1EditFieldLabel.Text = 'slop1';
            app.slop1EditField = uieditfield(app.UIFigure, 'numeric');
            app.slop1EditField.Limits = [0 1];
            app.slop1EditField.Value = 0.001;
            app.slop2EditFieldLabel = uilabel(app.UIFigure);
            app.slop2EditFieldLabel.Text = 'slop2';
            app.slop2EditField = uieditfield(app.UIFigure, 'numeric');
            app.slop2EditField.Limits = [0 1];
            app.slop2EditField.Value = 0.001;
            app.SmooothDegreeSliderLabel = uilabel(app.UIFigure);
            app.SmooothDegreeSliderLabel.Text = 'Smoooth Degree';
            app.SmooothDegreeSlider = uislider(app.UIFigure);
            app.SmooothDegreeSlider.Limits = [0.05 1];
            app.SmooothDegreeSlider.Value = 0.15;
            app.trendDropDownLabel = uilabel(app.UIFigure);
            app.trendDropDownLabel.Text = 'trend';
            app.trendDropDown = uidropdown(app.UIFigure);
            app.trendDropDown.Items = {'charge', 'discharge'};
            app.trendDropDown.Value = 'charge';
            app.stepsmoothEditFieldLabel = uilabel(app.UIFigure);
            app.stepsmoothEditFieldLabel.Text = 'step smooth';
            app.stepsmoothEditField = uieditfield(app.UIFigure, 'numeric');
            app.stepsmoothEditField.Limits = [0 100];
            app.stepsmoothEditField.Value = 10;
            app.smoothchoiceDropDownLabel = uilabel(app.UIFigure);
            app.smoothchoiceDropDownLabel.Text = 'smooth choice';
            app.smoothchoiceDropDown = uidropdown(app.UIFigure);
            app.smoothchoiceDropDown.Items = {'chap', 'rast'};
            app.smoothchoiceDropDown.Value = 'chap';
            app.andismaxorminLabel = uilabel(app.UIFigure);
            app.andismaxorminLabel.Text = 'andis max or min:';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.extract2Button = uibutton(app.UIFigure, 'push');
            app.extract2Button.ButtonPushedFcn = createCallbackFcn(app, @extract2ButtonPushed, true);
            app.extract2Button.Text = 'extract2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GITT3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end